classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PAZIGGUIUIFigure           matlab.ui.Figure
        WczytajPCButton            matlab.ui.control.Button
        WykonajSFSButton           matlab.ui.control.Button
        WczytajZdjecieButton       matlab.ui.control.Button
        WczytajzdjcieLabel         matlab.ui.control.Label
        WczytajchmurpunktwLabel    matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        ShapefromshadingLabel      matlab.ui.control.Label
        WygenerujButton            matlab.ui.control.Button
        WygenerujchmurpunktwLabel  matlab.ui.control.Label
        ShapefromshadingLabel_2    matlab.ui.control.Label
        PorwnajButton              matlab.ui.control.Button
        WywietlSzczegButton        matlab.ui.control.Button
        SzczegytransformacjiLabel  matlab.ui.control.Label
        PorwnajchmurypunktwLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        ZDJECIE; 
        AGI_PC;
        OBJ_PC;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajZdjecieButton
        function WczytajZdjecieButtonPushed(app, event)
            file = uigetfile({'*.jpg';'*.png'}, 'Wybierz zdjęcie');
            A = imread(file);
            A = imgaussfilt(A,1);
            A = rgb2gray(A);
            imshow(A, 'parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            
            app.ZDJECIE = A;
        end

        % Button pushed function: WykonajSFSButton
        function WykonajSFSButtonPushed(app, event)
            A = app.ZDJECIE;
            
            n = size(A,1);
            h = n*.4;
            A = rescale(A,0,h);

            figure('Name','Shape from shading','NumberTitle','off');
            surf(A);
            colormap(gray(256));
            shading interp;
            axis equal;
            view(110,45);
            axis('off');
            camlight;
        end

        % Button pushed function: WywietlSzczegButton
        function WywietlSzczegButtonPushed(app, event)
            A = app.ZDJECIE;
            
            n = size(A,1);
            n2 = size(A,2);
            h = n*.4;
            A = rescale(A,0,h);
            B = ones(n,n2) ;
            
            doklejenie1 = cat(2,A, A(:,n2));
            doklejenie2 = cat(1,A, A(n,:));
             
            pochodnax = diff(doklejenie2,1,1);
            pochodnay = diff(doklejenie1,1,2);
            
            N0 = cat(3, -pochodnax,-pochodnay, B); 
            s = sqrt(sum(N0.^2,3) );
            Q = repmat( s, [1 1 3] );
            N = N0 ./ Q;
            figure('Name','Kolorowy obraz obliczonej normalnej do powierzchni','NumberTitle','off')
            imshow(N);
            
            d = [0 0 1];
            R = reshape(d, [1 1 3]);
            W = repmat(R, [n n2 1]);
            
            T = N .* W;
            Y = sum( T,3 );
            L = max(0,Y);
            
            vmin = .3;
            P = max(L,vmin);
            figure('Name','Model odbicia Lambertiana','NumberTitle','off')
            imshow(P);
            
            d = [0 0 1];
            M = reshape(d,[1 1 3]);
            V = repmat(M,[n n2 1]);
            Z = N .* V ;
            L = sum( Z,3 );
            
            epsilon = 1e-9;
            A2 = L>1-epsilon;
            figure('Name','Model odbicia Lambertiana','NumberTitle','off');
            imshow(A2,[]);
            
            W = sqrt(1./L.^2-1);
            W = max(W,epsilon);
            figure('Name','Model odbicia Lambertiana','NumberTitle','off');;
            imshow(min(W,3));
        end

        % Button pushed function: WygenerujButton
        function WygenerujButtonPushed(app, event)
            A = app.ZDJECIE;
            
            A = imrotate(A,180);
            n = size(A,1);
            n2 = size(A,2);
            z = n*n2;

            wektX = (1:1:n)';

            w = wektX;
            for i = 1:n-1
                wektX = [wektX w];
            end

            wektX = wektX(:);
            wektY = sort(wektX);

            transposeA = A';
            wektZ = transposeA(:);
            
            xyzPoints = [wektX wektY wektZ];
            xyzPoints = single(xyzPoints);

            for i = 1:z
                if(wektZ(i)<=10)
                  xyzPoints(i,:) = 100;  
                end
            end
            
            xyzPoints = normalize(xyzPoints,'range');
            ptCloud_obj = pointCloud(xyzPoints);
            app.OBJ_PC = ptCloud_obj;
            figure('Name','Chmura punktów','NumberTitle','off')
            pcshow(ptCloud_obj)
            axis tight
        end

        % Button pushed function: WczytajPCButton
        function WczytajPCButtonPushed(app, event)
            file = uigetfile({'*.txt'},'Wybierz plik');
            
            PC = dlmread(file);

            wektX = PC(:,1);
            wektY = PC(:,2);
            wektZ = PC(:,3);
            
            xyzPoints = [wektX wektY wektZ];
            xyzPoints = single(xyzPoints);
            xyzPoints = normalize(xyzPoints,'range');
            
            ptCloud_agi = pointCloud(xyzPoints);
            app.AGI_PC = ptCloud_agi;
            
            figure('Name','Chmura punktów','NumberTitle','off')
            pcshow(ptCloud_agi)
        end

        % Button pushed function: PorwnajButton
        function PorwnajButtonPushed(app, event)
            ptCloud_agi = app.AGI_PC;
            ptCloud_obj = app.OBJ_PC;
            
            figure('Name','Porównanie chmur punktów','NumberTitle','off')
            pcshowpair(ptCloud_agi,ptCloud_obj)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PAZIGGUIUIFigure = uifigure('Visible', 'off');
            app.PAZIGGUIUIFigure.Name = 'PAZIG GUI';
            app.WczytajPCButton = uibutton(app.PAZIGGUIUIFigure, 'push');
            app.WczytajPCButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajPCButtonPushed, true);
            app.WczytajPCButton.Text = 'Wczytaj';
            app.WykonajSFSButton = uibutton(app.PAZIGGUIUIFigure, 'push');
            app.WykonajSFSButton.ButtonPushedFcn = createCallbackFcn(app, @WykonajSFSButtonPushed, true);
            app.WykonajSFSButton.Text = 'Wykonaj';
            app.WczytajZdjecieButton = uibutton(app.PAZIGGUIUIFigure, 'push');
            app.WczytajZdjecieButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajZdjecieButtonPushed, true);
            app.WczytajZdjecieButton.Text = 'Wczytaj';
            app.WczytajzdjcieLabel = uilabel(app.PAZIGGUIUIFigure);
            app.WczytajzdjcieLabel.Text = 'Wczytaj zdjęcie';
            app.WczytajchmurpunktwLabel = uilabel(app.PAZIGGUIUIFigure);
            app.WczytajchmurpunktwLabel.Text = 'Wczytaj chmurę punktów';
            app.UIAxes = uiaxes(app.PAZIGGUIUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ShapefromshadingLabel = uilabel(app.PAZIGGUIUIFigure);
            app.ShapefromshadingLabel.Text = 'Shape from shading';
            app.WygenerujButton = uibutton(app.PAZIGGUIUIFigure, 'push');
            app.WygenerujButton.ButtonPushedFcn = createCallbackFcn(app, @WygenerujButtonPushed, true);
            app.WygenerujButton.Text = 'Wygeneruj';
            app.WygenerujchmurpunktwLabel = uilabel(app.PAZIGGUIUIFigure);
            app.WygenerujchmurpunktwLabel.Text = 'Wygeneruj chmurę punktów';
            app.ShapefromshadingLabel_2 = uilabel(app.PAZIGGUIUIFigure);
            app.ShapefromshadingLabel_2.Text = 'Shape from shading';
            app.PorwnajButton = uibutton(app.PAZIGGUIUIFigure, 'push');
            app.PorwnajButton.ButtonPushedFcn = createCallbackFcn(app, @PorwnajButtonPushed, true);
            app.PorwnajButton.Text = 'Porównaj';
            app.WywietlSzczegButton = uibutton(app.PAZIGGUIUIFigure, 'push');
            app.WywietlSzczegButton.ButtonPushedFcn = createCallbackFcn(app, @WywietlSzczegButtonPushed, true);
            app.WywietlSzczegButton.Text = 'Wyświetl';
            app.SzczegytransformacjiLabel = uilabel(app.PAZIGGUIUIFigure);
            app.SzczegytransformacjiLabel.Text = 'Szczegóły transformacji';
            app.PorwnajchmurypunktwLabel = uilabel(app.PAZIGGUIUIFigure);
            app.PorwnajchmurypunktwLabel.Text = 'Porównaj chmury punktów';
            % Show the figure after all components are created
            app.PAZIGGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PAZIGGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PAZIGGUIUIFigure)
        end
    end
end